classdef Sttlement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        SurfaceSettlementCurvePanel  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CalculatorforsurfacesettlementcausedbytunnellingLabel  matlab.ui.control.Label
        ParameterinputPanel          matlab.ui.container.Panel
        PlotButton                   matlab.ui.control.Button
        BetaEditField                matlab.ui.control.NumericEditField
        BetaEditFieldLabel           matlab.ui.control.Label
        ThetaEditField               matlab.ui.control.NumericEditField
        ThetaLabel                   matlab.ui.control.Label
        Gama3EditField               matlab.ui.control.NumericEditField
        u3Label                      matlab.ui.control.Label
        Gama1EditField               matlab.ui.control.NumericEditField
        u2Label                      matlab.ui.control.Label
        VlEditField                  matlab.ui.control.NumericEditField
        VlEditFieldLabel             matlab.ui.control.Label
        RmEditField                  matlab.ui.control.NumericEditField
        RadiusmLabel                 matlab.ui.control.Label
        HmEditField                  matlab.ui.control.NumericEditField
        DeepmLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            H = app.HmEditField.Value;
            R = app.RmEditField.Value;
            vl = app.VlEditField.Value/100;
            u0 = R*(1-sqrt(1-vl));
            u1 = R*app.Gama1EditField.Value/100;
            u2 = R - R^2/(R-u1);
            u3 = R*app.Gama3EditField.Value/100;
            theta = app.ThetaEditField.Value/180*pi;
            beta = app.BetaEditField.Value/180*pi;

           
            muu = vpa(sym('muu'));
            sekma = vpa(sym('sekma'));
            x = vpa(sym('x'));
            mui = vpa(('mui'));
            sekmai = vpa(('sekmai'));


            muii = H + u3*cos(theta)+mui*cos(theta)+sekmai*sin(theta);
            sekmaii = mui*sin(theta)-sekmai*cos(theta);

            a = tan(beta)/muu;
            b = (tan(beta))^2*pi/muu^2; 
            c = (x-sekma)^2;
            d = sqrt(R^2-(H-muu)^2);
            e = -d;

            ai = tan(beta)/muii;
            bi = (tan(beta))^2*pi/muii^2;
            ci = (x+u3*sin(theta)+sekmaii)^2;
            di = (R-u0+u2)*sqrt(1-(mui/(R-u0-u1))^2);
            ei = -di;

            al = H-R;
            bl = H+R;

            ali = u0 + u1 - R;
            bli = R -u0 -u1;

            FZ = a*exp(-b*c);
            FZi = ai*exp(-bi*ci);

            % 利用Legendre-Gauss求积法对FZ进行积分
            n = 20; 
            [xi, wi] = lgwt(n, e, d); 
            GZH = 0;
            for i = 1:n
                GZH = GZH + wi(i)*subs(FZ, sekma, xi(i));
            end

            ni = 20;
            [xii, wii] = lgwt(ni, ei, di); 
            GZHi = 0;
            for j = 1:ni
                GZHi = GZHi + wii(j)*subs(FZi, sekmai, xii(j));
            end

            % 利用Legendre-Gauss求积法对GZH进行积分

            m = 20;
            [xi, wi] = lgwt(m, al, bl);
            MH = 0;
            for i = 1:m
                MH = MH + wi(i)*subs(GZH, muu, xi(i));
            end

            mi = 20;
            [xii, wii] = lgwt(mi, ali, bli);
            MHi = 0;
            for j = 1:mi
                MHi = MHi + wii(j)*subs(GZHi, mui, xii(j));
            end


            MHX = -1000*(MH-MHi);


            x_range = linspace(-30, 30, 1000);
            MHX_values = zeros(size(x_range));

            for i = 1:length(x_range)
                MHX_values(i) = double(subs(MHX, x, x_range(i)));
            end
            plot(app.UIAxes, x_range, MHX_values);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParameterinputPanel = uipanel(app.UIFigure);
            app.ParameterinputPanel.Title = 'Parameter input';
            app.DeepmLabel = uilabel(app.ParameterinputPanel);
            app.DeepmLabel.Text = 'H (m)';
            app.HmEditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.RadiusmLabel = uilabel(app.ParameterinputPanel);
            app.RadiusmLabel.Text = 'R (m)';
            app.RmEditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.VlEditFieldLabel = uilabel(app.ParameterinputPanel);
            app.VlEditFieldLabel.Text = 'Vl (%)';
            app.VlEditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.u2Label = uilabel(app.ParameterinputPanel);
            app.u2Label.Text = 'Gama 1 (%)';
            app.Gama1EditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.u3Label = uilabel(app.ParameterinputPanel);
            app.u3Label.Text = 'Gama 3 (%)';
            app.Gama3EditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.ThetaLabel = uilabel(app.ParameterinputPanel);
            app.ThetaLabel.Text = 'Theta (°)';
            app.ThetaEditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.ParameterinputPanel);
            app.BetaEditFieldLabel.Text = 'Beta (°)';
            app.BetaEditField = uieditfield(app.ParameterinputPanel, 'numeric');
            app.PlotButton = uibutton(app.ParameterinputPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CalculatorforsurfacesettlementcausedbytunnellingLabel = uilabel(app.UIFigure);
            app.CalculatorforsurfacesettlementcausedbytunnellingLabel.Text = 'Calculator for surface settlement caused by tunnelling';
            app.SurfaceSettlementCurvePanel = uipanel(app.UIFigure);
            app.SurfaceSettlementCurvePanel.Title = 'Surface Settlement Curve';
            app.UIAxes = uiaxes(app.SurfaceSettlementCurvePanel);
            xlabel(app.UIAxes, 'Distance From The Tunnel Center (m)')
            ylabel(app.UIAxes, 'Settlement (mm)')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '118.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sttlement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end