classdef Tutorial2_SimpleCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Lamp                   matlab.ui.control.Lamp
        WeightNEditField       matlab.ui.control.NumericEditField
        WeightNEditFieldLabel  matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        Accms2EditField        matlab.ui.control.NumericEditField
        Accms2EditFieldLabel   matlab.ui.control.Label
        MsaaKgEditField        matlab.ui.control.NumericEditField
        MsaaKgEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            mass = app.MsaaKgEditField.Value;
            acc = app.Accms2EditField.Value;
% Newton's Sencond Law F = ma
            Force = mass*acc;
             app.WeightNEditField.Value = Force;

            if Force > 0 && Force <= 500
                app.Lamp.Color = 'green';
            elseif Force > 500 && Force <= 1000
                app.Lamp.Color = [0.93,0.69,0.13]; % Orange
            else
                app.Lamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MsaaKgEditFieldLabel = uilabel(app.UIFigure);
            app.MsaaKgEditFieldLabel.Text = 'Msaa(Kg)';
            app.MsaaKgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.UIFigure);
            app.Accms2EditFieldLabel.Text = 'Acc(m/s^2)';
            app.Accms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WeightNEditFieldLabel = uilabel(app.UIFigure);
            app.WeightNEditFieldLabel.Text = 'Weight(N)';
            app.WeightNEditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tutorial2_SimpleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end