classdef Tutorial4_Lamp_Indicator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculatorLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Lamp                    matlab.ui.control.Lamp
        WeightNEditField        matlab.ui.control.NumericEditField
        WeightNEditFieldLabel   matlab.ui.control.Label
        Accms2EditField         matlab.ui.control.NumericEditField
        Accms2EditFieldLabel    matlab.ui.control.Label
        MsaaKgEditField         matlab.ui.control.NumericEditField
        MsaaKgEditFieldLabel    matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            mass = app.MsaaKgEditField.Value;
            acc = app.Accms2EditField.Value;
% Newton's Sencond Law F = ma
            Force = mass*acc;

            app.WeightNEditField.Value = Force;

            if Force > 0 && Force <= 500
                app.Lamp.Color = 'green';
            elseif Force > 500 && Force <= 1000
                app.Lamp.Color = [0.93,0.69,0.13]; % Orange
            else
                app.Lamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MsaaKgEditFieldLabel = uilabel(app.Panel);
            app.MsaaKgEditFieldLabel.Text = 'Msaa(Kg)';
            app.MsaaKgEditField = uieditfield(app.Panel, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.Panel);
            app.Accms2EditFieldLabel.Text = 'Acc(m/s^2)';
            app.Accms2EditField = uieditfield(app.Panel, 'numeric');
            app.WeightNEditFieldLabel = uilabel(app.Panel);
            app.WeightNEditFieldLabel.Text = 'Weight(N)';
            app.WeightNEditField = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newton''s Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tutorial4_Lamp_Indicator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end